classdef SimpleCalcy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Button_26    matlab.ui.control.Button
        CLEARButton  matlab.ui.control.Button
        Button_23    matlab.ui.control.Button
        Button_25    matlab.ui.control.Button
        Button_21    matlab.ui.control.Button
        Button_20    matlab.ui.control.Button
        Button_19    matlab.ui.control.Button
        Button_18    matlab.ui.control.Button
        Button_17    matlab.ui.control.Button
        Button_16    matlab.ui.control.Button
        Button_15    matlab.ui.control.Button
        Button_14    matlab.ui.control.Button
        Button_13    matlab.ui.control.Button
        Button_12    matlab.ui.control.Button
        Button_11    matlab.ui.control.Button
        Button       matlab.ui.control.Button
        TextArea     matlab.ui.control.TextArea
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button, Button_11, Button_12, Button_13, 
        % ...and 10 other components
        function ButtonPress(app, event)
            app.TextArea.Value = [app.TextArea.Value{1} event.Source.Text];
        end

        % Button pushed function: CLEARButton
        function ButtonClear(app, event)
            app.TextArea.Value = '';
        end

        % Button pushed function: Button_26
        function ButtonEqual(app, event)
            try
                app.TextArea.Value = [app.TextArea.Value{1} event.Source.Text num2str(eval(app.TextArea.Value{1}))];
            catch
                app.TextArea.Value = 'Error';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'                                                     0'};
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPress, true);
            app.Button.Text = '1';
            app.Button_11 = uibutton(app.UIFigure, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @ButtonPress, true);
            app.Button_11.Text = '2';
            app.Button_12 = uibutton(app.UIFigure, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @ButtonPress, true);
            app.Button_12.Text = '3';
            app.Button_13 = uibutton(app.UIFigure, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @ButtonPress, true);
            app.Button_13.Text = '4';
            app.Button_14 = uibutton(app.UIFigure, 'push');
            app.Button_14.ButtonPushedFcn = createCallbackFcn(app, @ButtonPress, true);
            app.Button_14.Text = '5';
            app.Button_15 = uibutton(app.UIFigure, 'push');
            app.Button_15.ButtonPushedFcn = createCallbackFcn(app, @ButtonPress, true);
            app.Button_15.Text = '6';
            app.Button_16 = uibutton(app.UIFigure, 'push');
            app.Button_16.ButtonPushedFcn = createCallbackFcn(app, @ButtonPress, true);
            app.Button_16.Text = '7';
            app.Button_17 = uibutton(app.UIFigure, 'push');
            app.Button_17.ButtonPushedFcn = createCallbackFcn(app, @ButtonPress, true);
            app.Button_17.Text = '8';
            app.Button_18 = uibutton(app.UIFigure, 'push');
            app.Button_18.ButtonPushedFcn = createCallbackFcn(app, @ButtonPress, true);
            app.Button_18.Text = '9';
            app.Button_19 = uibutton(app.UIFigure, 'push');
            app.Button_19.ButtonPushedFcn = createCallbackFcn(app, @ButtonPress, true);
            app.Button_19.Text = '0';
            app.Button_20 = uibutton(app.UIFigure, 'push');
            app.Button_20.ButtonPushedFcn = createCallbackFcn(app, @ButtonPress, true);
            app.Button_20.Text = '+';
            app.Button_21 = uibutton(app.UIFigure, 'push');
            app.Button_21.ButtonPushedFcn = createCallbackFcn(app, @ButtonPress, true);
            app.Button_21.Text = '-';
            app.Button_25 = uibutton(app.UIFigure, 'push');
            app.Button_25.ButtonPushedFcn = createCallbackFcn(app, @ButtonPress, true);
            app.Button_25.Text = '*';
            app.Button_23 = uibutton(app.UIFigure, 'push');
            app.Button_23.ButtonPushedFcn = createCallbackFcn(app, @ButtonPress, true);
            app.Button_23.Text = '/';
            app.CLEARButton = uibutton(app.UIFigure, 'push');
            app.CLEARButton.ButtonPushedFcn = createCallbackFcn(app, @ButtonClear, true);
            app.CLEARButton.Text = 'CLEAR';
            app.Button_26 = uibutton(app.UIFigure, 'push');
            app.Button_26.ButtonPushedFcn = createCallbackFcn(app, @ButtonEqual, true);
            app.Button_26.Text = '=';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SimpleCalcy

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end